classdef COVID19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationAppUIFigure  matlab.ui.Figure
        SearchStatesEditField     matlab.ui.control.EditField
        SearchCountriesEditField  matlab.ui.control.EditField
        AvaragedofDaysLabel       matlab.ui.control.Label
        StatesorRegionListBox     matlab.ui.control.ListBox
        StatesorRegionLabel       matlab.ui.control.Label
        AvaragedofDaysSlider      matlab.ui.control.Slider
        CountryListBox            matlab.ui.control.ListBox
        CountryLabel              matlab.ui.control.Label
        OptionButtonGroup         matlab.ui.container.ButtonGroup
        DailyButton               matlab.ui.control.RadioButton
        CumulativeButton          matlab.ui.control.RadioButton
        DatatoPlotButtonGroup     matlab.ui.container.ButtonGroup
        BothButton                matlab.ui.control.RadioButton
        DeathsButton              matlab.ui.control.RadioButton
        CasesButton               matlab.ui.control.RadioButton
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        global_data 
    end
    
    methods (Access = private)
        
       function Global = get_object(~,data)
            field=fieldnames(data);  % Get the field name of the loaded data (e.g., the variable name inside the .mat file)
            
            % Initialize the first country as 'Global' (World-Wide-Data)
            Global = Region({'Global'},Region.empty,{'All'},data.(field{1})(2:end,3:end),data.(field{1})(1,3:end));
            Global.Country(1)=Region({'Global'},{'Global'},{'All'},data.(field{1})(2:end,3:end),{});
            % Sum all global cases and deaths to populate 'Global' country's data
            Global.Country(1).Cases = sum(Global.Cases, 1);
            Global.Country(1).Deaths = sum(Global.Deaths, 1);
            % Pre-allocate Region objects for each country (excluding the first 'Global')
            Global.Country(2:length(unique(data.(field{1})(2:end,1)))+1) = Region({},{},{},{},{});

            % Initialize country index (Skip the first index as it is reserved for 'Global')
            obj_idx=2; i=2;
            while i<=length(data.(field{1})(2:end,1))
                % Extract the country name from row i+1 (data starts at row 2)
                  country = data.(field{1})(i+1,1);
                  % Find all rows that belong to this country
                  No_of_states = find(strcmp(data.(field{1})(:,1) , country));

                  % Extract states and corresponding data for this country
                  for j=1:length(No_of_states)
                    states = data.(field{1})(No_of_states(1):No_of_states(end),2);
                    cases = data.(field{1})(No_of_states(1):No_of_states(end),3:end);
                  end 
                  
                  % Create a Region object for this country with its states and cases
                  Global.Country(obj_idx)=Region({'Global'},country,states,cases,{});
                  obj_idx=obj_idx+1;
                  % Skip to the next unprocessed country
                  i=i+length(No_of_states);
            end
        end
        
        function  Plot_Data(app) 
      
                  Selected_Country = app.CountryListBox.Value;   % Get selected country and averaging window from UI
                  AvgWindow = round(app.AvaragedofDaysSlider.Value);

                  if isempty(Selected_Country) , return; end     % Exit if no country is selected
                  idx=find(strcmp([app.global_data.Country.Country],Selected_Country));  % Find index of selected country in the global data

                  Selected_State = app.StatesorRegionListBox.Value;  % Get selected state/region from UI
                  State_idx=1; %Default State is All

                  % If a specific state is selected (not 'All'), update State_idx and country label
                  if ~isempty(Selected_State) && ~strcmp(Selected_State,'All')
                      State_idx=find(strcmp([app.global_data.Country(idx).States],Selected_State));
                      Selected_Country=Selected_State;
                  end

                  mode='Cumulative';   % Determine mode: Cumulative(Default) or Daily
                  if app.DailyButton.Value , mode='Daily';  end 

                  option='Cases';   % Determine data type: Cases(Default), Deaths, or Both
                  if app.DeathsButton.Value , option='Deaths';  end 
                  if app.BothButton.Value , option='Cases and Deaths';  end 
                 
                  dates = app.global_data.Dates;    % Parse date strings into datetime format
                  dates_dt = datetime(dates, 'InputFormat', 'MM/dd/yy');
                 
                  % Clear previous plots
                  cla(app.UIAxes)
                  % Set to left y-axis and configure appearance
                  yyaxis(app.UIAxes,'left')
                  app.UIAxes.YAxis(1).Color = [0.15 0.15 0.15];
                  app.UIAxes.YAxis(2).Visible = 'off';    % Hide right y-axis by default
                  % Plot Cases if requested
                  if app.CasesButton.Value || app.BothButton.Value
                         Cases_Plot=ComputeFieldAvg(app.global_data.Country(idx), 'Cases' ,mode,AvgWindow,State_idx);
                         bar(app.UIAxes,dates_dt, Cases_Plot);
                  end
                  % Plot Deaths if requested
                  if app.DeathsButton.Value || app.BothButton.Value
                         Deaths_Plot=ComputeFieldAvg(app.global_data.Country(idx), 'Deaths' ,mode,AvgWindow,State_idx);
                         if strcmp(option,'Deaths')
                             plot(app.UIAxes,dates_dt,Deaths_Plot,'r');
                         else
                            % Use dual y-axes for both Cases and Deaths
                            app.UIAxes.YAxis(1).Color = [0, 0.4470, 0.7410];
                            yyaxis(app.UIAxes,'right')
                            app.UIAxes.YAxis(2).Visible = 'on';
                            plot(app.UIAxes,dates_dt,Deaths_Plot,'r'); % Right y-axis for deaths
                            app.UIAxes.YAxis(2).Exponent = 0;  % Remove exponent formatting 
                         end
                  end 
                  % Set dynamic title based on mode, data type, location, and window size
                  if AvgWindow > 1
                        fullTitle = sprintf('%s Number of %s in %s (%d-day mean)', mode, option, Selected_Country, AvgWindow);
                  else
                        fullTitle = sprintf('%s Number of %s in %s', mode,option, Selected_Country);
                  end
                  title(app.UIAxes, fullTitle);
                  % Final formatting of axes
                  app.UIAxes.Box = 'off';
                  app.UIAxes.YGrid = 'on';
                  app.UIAxes.XGrid = 'on';
                  app.UIAxes.YAxis(1).Exponent = 0; % Remove scientific notation
                  ytickformat(app.UIAxes, '%.0f');  % Integer format for Y ticks
                  % Format X-axis ticks
                  app.UIAxes.XTick = dates_dt(1:90:end);
                  app.UIAxes.XAxis.TickLabelFormat = 'MMM yy';
                  app.UIAxes.XTick = [app.UIAxes.XTick, app.UIAxes.XTick(end) + calmonths(3)];
                  app.UIAxes.XLim = [min(app.UIAxes.XTick), max(app.UIAxes.XTick)];  


        end 

        function Search(~, ListBoxName, SearchVal, Contents)
                 ListBoxName.Value = {};   % Clear the current selection
                 if isempty(SearchVal)
                    ListBoxName.Items = Contents;  % Show all items
                 else
                    MatchIdx = find(cellfun(@(x) contains(x, SearchVal, 'IgnoreCase', true), Contents)); % Find matching items
                    if ~isempty(MatchIdx)
                        ListBoxName.Items = Contents(MatchIdx);  % Set filtered list
                    else
                        ListBoxName.Items = {}; % No matches
                    end
                end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data=load('covid_data.mat');   
            % Convert raw data into structured Region objects 
            app.global_data= app.get_object(data);     
            % Set the Country list (Default is 'Global')
            app.CountryListBox.Items=[app.global_data.Country.Country];  
            app.CountryListBox.Value = app.CountryListBox.Items(1); 
            % Set state list for 'Global' > 'All'
            app.StatesorRegionListBox.Items = app.global_data.States;   
            app.StatesorRegionListBox.Value = app.StatesorRegionListBox.Items(1);
            app.Plot_Data();  % Plot initial data
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            Selected_Country = event.Value;
            app.SearchStatesEditField.Value = '';   % Clear state search field at each selected country
            if ~isempty(Selected_Country)
               idx=find(strcmp([app.global_data.Country.Country],Selected_Country));
               % Add 'All' as first state (Represents all country data)
               app.global_data.Country(idx).States(1) = {'All'} ;
               % Update state list based on the selected country
               app.StatesorRegionListBox.Items =  app.global_data.Country(idx).States;   
               app.StatesorRegionListBox.Value = app.StatesorRegionListBox.Items(1);
            end
            app.Plot_Data();
        end

        % Value changed function: StatesorRegionListBox
        function StatesorRegionListBoxValueChanged(app, event)
            app.Plot_Data();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.Plot_Data();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.Plot_Data();
        end

        % Value changing function: SearchCountriesEditField
        function SearchCountriesEditFieldValueChanging(app, event)
            if isempty(event.Value)  % If country search field is empty, clear state search field and list
                app.SearchStatesEditField.Value = '';
                app.StatesorRegionListBox.Items = {};
            end
            app.Search(app.CountryListBox, event.Value,[app.global_data.Country.Country]);
        end

        % Value changing function: SearchStatesEditField
        function SearchStatesEditFieldValueChanging(app, event)
            app.StatesorRegionListBox.Value = {};   % Clear current selected state
            Selected_Country = app.CountryListBox.Value;
            if ~isempty(Selected_Country)
                idx=find(strcmp([app.global_data.Country.Country],Selected_Country));
                app.Search( app.StatesorRegionListBox, event.Value,[app.global_data.Country(idx).States]);
            end
        end

        % Value changed function: AvaragedofDaysSlider
        function AvaragedofDaysSliderValueChanged(app, event)
            app.AvaragedofDaysSlider.Value = event.Value;
            app.Plot_Data();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationAppUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationAppUIFigure.Name = 'COVID-19 Data Visualization App';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationAppUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationAppUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationAppUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.CountryLabel = uilabel(app.COVID19DataVisualizationAppUIFigure);
            app.CountryLabel.Text = 'Country :';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationAppUIFigure);
            app.CountryListBox.Items = {''};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.AvaragedofDaysSlider = uislider(app.COVID19DataVisualizationAppUIFigure);
            app.AvaragedofDaysSlider.Limits = [1 15];
            app.AvaragedofDaysSlider.ValueChangedFcn = createCallbackFcn(app, @AvaragedofDaysSliderValueChanged, true);
            app.AvaragedofDaysSlider.Value = 1;
            app.StatesorRegionLabel = uilabel(app.COVID19DataVisualizationAppUIFigure);
            app.StatesorRegionLabel.Text = {'States or '; 'Region :'};
            app.StatesorRegionListBox = uilistbox(app.COVID19DataVisualizationAppUIFigure);
            app.StatesorRegionListBox.Items = {''};
            app.StatesorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StatesorRegionListBoxValueChanged, true);
            app.StatesorRegionListBox.Value = {};
            app.AvaragedofDaysLabel = uilabel(app.COVID19DataVisualizationAppUIFigure);
            app.AvaragedofDaysLabel.Text = {'Avaraged'; ' # of Days'};
            app.SearchCountriesEditField = uieditfield(app.COVID19DataVisualizationAppUIFigure, 'text');
            app.SearchCountriesEditField.ValueChangingFcn = createCallbackFcn(app, @SearchCountriesEditFieldValueChanging, true);
            app.SearchCountriesEditField.Placeholder = 'Search';
            app.SearchStatesEditField = uieditfield(app.COVID19DataVisualizationAppUIFigure, 'text');
            app.SearchStatesEditField.ValueChangingFcn = createCallbackFcn(app, @SearchStatesEditFieldValueChanging, true);
            app.SearchStatesEditField.Placeholder = 'Search';
            % Show the figure after all components are created
            app.COVID19DataVisualizationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationAppUIFigure)
        end
    end
end